classdef FileCompressionProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageCompressionDecompressionUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        CompressionMenu                 matlab.ui.container.Menu
        DecompressionMenu               matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        VideoMenu                       matlab.ui.container.Menu
        CompressionMenu_2               matlab.ui.container.Menu
        DecompressionMenu_2             matlab.ui.container.Menu
        ExitMenu_2                      matlab.ui.container.Menu
        SelectFileButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CompressionRatioEditFieldLabel  matlab.ui.control.Label
        CompressionRatioEditField       matlab.ui.control.EditField
        LblSelectFile                   matlab.ui.control.Label
        CompressFileButton              matlab.ui.control.Button
        DecompressFileButton            matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        CompressionSizeEditFieldLabel   matlab.ui.control.Label
        CompressionSizeEditField        matlab.ui.control.EditField
        ClearAllButton                  matlab.ui.control.StateButton
        OriginalSizeEditFieldLabel      matlab.ui.control.Label
        OriginalSizeEditField           matlab.ui.control.EditField
    end
    
    methods (Access = private)
    
        % Menu selected function: CompressionMenu
        function CompressionMenuSelected(app, event)
            app.LblSelectFile.Visible=true;
            app.LblSelectFile.Enable=true;
            app.SelectFileButton.Enable=true;
            %print('Enter file for compression');
        end
        
        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global fname;
            [filename pathname]=uigetfile({'*.bmp'},'File Selector');
            fname=fullfile(pathname,filename);
            imshow(fname,'Parent',app.UIAxes);
            app.CompressFileButton.Enable=true;
            app.LblSelectFile.Text=fname;
        end
        
         % Button pushed function: CompressFileButton
        function CompressFileButtonPushed(app, event)
            app.LblSelectFile.Visible=false;
            fname=app.LblSelectFile.Text;
            msgbox('done');
        
    end
    
    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageCompressionDecompressionUIFigure = uifigure;
            app.ImageCompressionDecompressionUIFigure.Name = 'Image Compression/Decompression';
            app.FileMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.FileMenu.Text = 'File';
            app.CompressionMenu = uimenu(app.FileMenu);
            app.CompressionMenu.MenuSelectedFcn = createCallbackFcn(app, @CompressionMenuSelected, true);
            app.CompressionMenu.Tooltip = {'Open file for compression'};
            app.CompressionMenu.Text = 'Compression';
            app.DecompressionMenu = uimenu(app.FileMenu);
            app.DecompressionMenu.Text = 'Decompression';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.VideoMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.VideoMenu.Text = 'Video';
            app.CompressionMenu_2 = uimenu(app.VideoMenu);
            app.CompressionMenu_2.Text = 'Compression';
            app.DecompressionMenu_2 = uimenu(app.VideoMenu);
            app.DecompressionMenu_2.Text = 'Decompression';
            app.ExitMenu_2 = uimenu(app.VideoMenu);
            app.ExitMenu_2.Text = 'Exit';
            app.SelectFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Enable = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.UIAxes = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes, 'Input Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CompressionRatioEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionRatioEditFieldLabel.Text = 'Compression Ratio';
            app.CompressionRatioEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.LblSelectFile = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.LblSelectFile.Enable = 'off';
            app.LblSelectFile.Visible = 'off';
            app.LblSelectFile.Text = 'Select file';
            app.CompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.CompressFileButton.ButtonPushedFcn = createCallbackFcn(app, @CompressFileButtonPushed, true);
            app.CompressFileButton.Enable = 'off';
            app.CompressFileButton.Tooltip = {'Click to compress file'};
            app.CompressFileButton.Text = 'Compress File';
            app.DecompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.DecompressFileButton.Enable = 'off';
            app.DecompressFileButton.Tooltip = {'Click to decompress file'};
            app.DecompressFileButton.Text = 'Decompress File';
            app.UIAxes2 = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes2, 'Output  Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.CompressionSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionSizeEditFieldLabel.Text = 'Compression Size';
            app.CompressionSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.ClearAllButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'state');
            app.ClearAllButton.Text = {'Clear All'; ''};
            app.OriginalSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.OriginalSizeEditFieldLabel.Text = 'Original Size';
            app.OriginalSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
        end
        
    end

end